classdef LSS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoFArmControlUIFigure          matlab.ui.Figure
        STOPButton_2                   matlab.ui.control.Button
        VIEWButton                     matlab.ui.control.Button
        SENDButton                     matlab.ui.control.Button
        UPDATEButton_2                 matlab.ui.control.Button
        LEDDropDown                    matlab.ui.control.DropDown
        LEDDropDownLabel               matlab.ui.control.Label
        LSS4DoFRoboticArmControlLabel  matlab.ui.control.Label
        COMDropDown                    matlab.ui.control.DropDown
        COMDropDownLabel               matlab.ui.control.Label
        BAUDDropDown                   matlab.ui.control.DropDown
        BAUDDropDownLabel              matlab.ui.control.Label
        PositionPanel                  matlab.ui.container.Panel
        YmmSlider                      matlab.ui.control.Slider
        YmmSliderLabel                 matlab.ui.control.Label
        ZmmSlider                      matlab.ui.control.Slider
        ZmmSliderLabel                 matlab.ui.control.Label
        XmmSlider                      matlab.ui.control.Slider
        XmmSliderLabel                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global s;
            global q;
            global LSSrobot;
            
            % LSS Arm joint lenghts (inches)
            d1 = 4.13;   % Bottom to shoulder
            d2 = 5.61;   % Shoulder to elbow
            d3 = 6.39;   % Elbow to wrist
            d4 = 4.52;   % Wrist to end of gripper
            
            % Links for 4DoF Arm
            L(1) = Link([0 d1 0 pi/2]);
            L(2) = Link([0 0 d2 0]);
            L(3) = Link([0 0 d3 0]);
            L(4) = Link([0 0 d4 0]);
            
            % Joint limit angles
            L(1).qlim = [-pi pi];
            L(2).qlim = [-pi/2 pi/2];
            L(3).qlim = [-pi/2 pi/2];
            L(4).qlim = [-pi/2 pi/2];
            
            % Joint offsets
            L(1).offset = pi;
            L(2).offset = pi/2;
            L(3).offset = pi/2;
            
            % Serial Links -> Robotic Arm
            LSSrobot = SerialLink(L);
            LSSrobot.name = "LSS Robot";
            LSSrobot.plotopt = {'workspace', [-20,20,-20,20,0,20], 'tile1color', [232 232 232], 'jointcolor', 'c','noarrow', 'nowrist'};
            
            % Display robot in teach mode
            q = [0 0 0 0];
            LSSrobot.teach(q);
            
            if isempty(seriallist)
                helpdlg({'LSS arm is not connected',...
                         'Please connect the arm to the PC'});
                app.COMDropDown.Value = "OFF";
            else
                delete(instrfind);
                s = serial(seriallist);
                s.Baudrate = str2double(app.BAUDDropDown.Value);
                s.Terminator = 'CR';
                fopen(s);
                
                % Configure Max Speed to 60 deg/s
                fprintf(s, sprintf('#%d%s%d', 254, 'CSD', 60));
                
                % Disable the Trapezoidal Motion Profile (shoulder, elbow
                % and wrist)
                fprintf(s, sprintf('#%d%s%d', 2, 'EM', 0));
                fprintf(s, sprintf('#%d%s%d', 3, 'EM', 0));
                fprintf(s, sprintf('#%d%s%d', 4, 'EM', 0));
                
                % Change the Filter Position Count
                fprintf(s, sprintf('#%d%s%d', 2, 'FPC', 15));
                fprintf(s, sprintf('#%d%s%d', 3, 'FPC', 15));
                fprintf(s, sprintf('#%d%s%d', 4, 'FPC', 15));
            end
        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            global s;
            
            % Get interface values
            base_ang = app.BaseEditField.Value;
            shoulder_ang = app.ShoulderEditField.Value;
            elbow_ang = app.ElbowEditField.Value;
            wrist_ang = app.WristEditField.Value;
            gripper_ang = app.GripperEditField.Value;
            
            if app.COMDropDown.Value == "ON"
                % Limit LSS angle values to [-90,90]
                base = min(max(base_ang,-90),90);
                shoulder = min(max(shoulder_ang,-90),90);
                elbow = min(max(elbow_ang,-90),90);
                wrist = min(max(wrist_ang,-90),90);
                gripper = min(max(-gripper_ang,-90),0);
                
                % Send serial commands to LSS Arm
                fprintf(s, sprintf('#%d%s%d', 1, 'D', round((base)*10)));
                fprintf(s, sprintf('#%d%s%d', 2, 'D', round((shoulder)*10)));
                fprintf(s, sprintf('#%d%s%d', 3, 'D', round((elbow)*10)));
                fprintf(s, sprintf('#%d%s%d', 4, 'D', round((wrist)*10)));
                fprintf(s, sprintf('#%d%s%d%s%d', 5, 'D', round((gripper)*10), 'CH', 400));
            else
                helpdlg({'COM port is OFF',...
                         'Please turn it ON'});
            end
        end

        % Button pushed function: VIEWButton
        function VIEWButtonPushed(app, event)
            global q;
            global LSSrobot;
        
            % Get interface values
            X = app.XmmSlider.Value;
            Y = app.YmmSlider.Value;
            Z = app.ZmmSlider.Value;
            G = app.GSlider.Value;
            
            Ti = [1 0 0 X;
                  0 1 0 Y;
                  0 0 1 Z;
                  0 0 0 1];
                  
            % Calculate inverse kinematics
            J = LSSrobot.ikcon(Ti, q)*180/pi;
            
            if isempty(J)   % Error message
               helpdlg({'Error: Failed to converge',...
                        'Try different values of joint coordinates'});
            else             
                % Update interface values in the forward kinematics panel
                app.BaseEditField.Value = round(J(1));
                app.ShoulderEditField.Value = round(J(2));
                app.ElbowEditField.Value = round(J(3));
                app.WristEditField.Value = round(J(4));
                app.GripperEditField.Value = round(G);
                
                % Plot results
                q = J*pi/180;
                LSSrobot.plot(q);
            end
            
            clear J;
        end

        % Callback function
        function LIMPButtonPushed(app, event)
            global s;
            pause(2);
            if app.COMDropDown.Value == "ON"
                fprintf(s, sprintf('#%d%s', 254, 'L'));
            else
                helpdlg({'COM port is OFF',...
                         'Please turn it ON'});
            end
        end

        % Value changed function: BAUDDropDown
        function BAUDDropDownValueChanged(app, event)
            global s;
            if app.COMDropDown.Value == "ON"
                fprintf(s, sprintf('#%d%s%d', 254, 'CB', str2double(app.BAUDDropDown.Value)));
                fprintf(s, sprintf('#%d%s', 254, 'RESET'));
                pause(2);
                fprintf(s, sprintf('#%d%s', 254, 'CONFIRM'));
                delete(instrfind);
                s = serial(seriallist);
                s.Baudrate = str2double(app.BAUDDropDown.Value);
                s.Terminator = 'CR';
                fopen(s);
            else
                helpdlg({'COM port is OFF',...
                         'Please turn it ON'});
            end
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            global s;
            if app.COMDropDown.Value == "ON"
                if isempty(seriallist)
                    helpdlg({'LSS arm is not connected',...
                             'Please connect the arm to the PC'});
                    app.COMDropDown.Value = "OFF";
                else
                    delete(instrfind);
                    s = serial(seriallist);
                    s.Baudrate = str2double(app.BAUDDropDown.Value);
                    s.Terminator = 'CR';
                    fopen(s);
                end
            else
                fclose(s);
            end
        end

        % Close request function: DoFArmControlUIFigure
        function DoFArmControlUIFigureCloseRequest(app, event)
            global s;
            if ~isempty(seriallist)
                fclose(s);
                delete(instrfind);
            end
            delete(app);
        end

        % Value changed function: LEDDropDown
        function LEDDropDownValueChanged(app, event)
            LED = app.LEDDropDown.Value;
            global s
            if app.COMDropDown.Value == "ON"
                switch LED
                    case "Black"
                        fprintf(s, sprintf('#%d%s%d', 254, 'LED', 0));
                    case "Red"
                        fprintf(s, sprintf('#%d%s%d', 254, 'LED', 1));
                    case "Green"
                        fprintf(s, sprintf('#%d%s%d', 254, 'LED', 2));
                    case "Blue"
                        fprintf(s, sprintf('#%d%s%d', 254, 'LED', 3));
                    case "Yellow"
                        fprintf(s, sprintf('#%d%s%d', 254, 'LED', 4));
                    case "Cyan"
                        fprintf(s, sprintf('#%d%s%d', 254, 'LED', 5));
                    case "Magenta"
                        fprintf(s, sprintf('#%d%s%d', 254, 'LED', 6));
                    case "White"
                        fprintf(s, sprintf('#%d%s%d', 254, 'LED', 7));
                end
            else
                helpdlg({'COM port is OFF',...
                         'Please turn it ON'});
            end
        end

        % Button pushed function: UPDATEButton_2
        function UPDATEButton_2Pushed(app, event)
            global q;
            global LSSrobot;

            % Get joint coordinates from graphical display
            q = LSSrobot.getpos();

            % Update interface values in forward kinematics panel
            app.BaseEditField.Value = round(q(1)*180/pi);
            app.ShoulderEditField.Value = round(q(2)*180/pi);
            app.ElbowEditField.Value = round(q(3)*180/pi);
            app.WristEditField.Value = round(q(4)*180/pi);
            
            % Calculate forward kinematics
            T = LSSrobot.fkine(q);
            [x,y,z] = transl(T);
            
            % Update interface values in inverse kinematics panel
            app.XmmSlider.Value = round(x);
            app.YmmSlider.Value = round(y);
            if round(z) > 0
                app.ZmmSlider.Value = round(z);
            else
                helpdlg({'Z value is out of limits',...
                         'Please change the joint positions'});
            end
        end

        % Button pushed function: STOPButton_2
        function STOPButtonPushed(app, event)
            global s;
            if app.COMDropDown.Value == "ON"
                fprintf(s, sprintf('#%d%s', 254, 'H'));
            else
                helpdlg({'COM port is OFF',...
                         'Please turn it ON'});
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoFArmControlUIFigure = uifigure('Visible', 'off');
            app.DoFArmControlUIFigure.Name = '4 DoF Arm Control';
            app.DoFArmControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @DoFArmControlUIFigureCloseRequest, true);
            app.PositionPanel = uipanel(app.DoFArmControlUIFigure);
            app.PositionPanel.Title = 'Position';
            app.XmmSliderLabel = uilabel(app.PositionPanel);
            app.XmmSliderLabel.Text = 'X (mm)';
            app.XmmSlider = uislider(app.PositionPanel);
            app.XmmSlider.Limits = [-17 17];
            app.XmmSlider.MajorTicks = [-17 -12 -8 -4 0 4 8 12 17];
            app.XmmSlider.MinorTicks = [-17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17];
            app.ZmmSliderLabel = uilabel(app.PositionPanel);
            app.ZmmSliderLabel.Text = 'Z (mm)';
            app.ZmmSlider = uislider(app.PositionPanel);
            app.ZmmSlider.Limits = [1 22];
            app.ZmmSlider.Value = 1;
            app.YmmSliderLabel = uilabel(app.PositionPanel);
            app.YmmSliderLabel.Text = 'Y (mm)';
            app.YmmSlider = uislider(app.PositionPanel);
            app.YmmSlider.Limits = [-17 17];
            app.YmmSlider.MajorTicks = [-17 -12 -8 -4 0 4 8 12 17];
            app.YmmSlider.MinorTicks = [-17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17];
            app.BAUDDropDownLabel = uilabel(app.DoFArmControlUIFigure);
            app.BAUDDropDownLabel.Text = 'BAUD';
            app.BAUDDropDown = uidropdown(app.DoFArmControlUIFigure);
            app.BAUDDropDown.Items = {'2400', '9600', '38400', '115200'};
            app.BAUDDropDown.ValueChangedFcn = createCallbackFcn(app, @BAUDDropDownValueChanged, true);
            app.BAUDDropDown.Value = '115200';
            app.COMDropDownLabel = uilabel(app.DoFArmControlUIFigure);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.DoFArmControlUIFigure);
            app.COMDropDown.Items = {'ON', 'OFF'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'ON';
            app.LSS4DoFRoboticArmControlLabel = uilabel(app.DoFArmControlUIFigure);
            app.LSS4DoFRoboticArmControlLabel.Text = 'LSS 4DoF Robotic Arm Control';
            app.LEDDropDownLabel = uilabel(app.DoFArmControlUIFigure);
            app.LEDDropDownLabel.Text = 'LED';
            app.LEDDropDown = uidropdown(app.DoFArmControlUIFigure);
            app.LEDDropDown.Items = {'OFF', 'Red', 'Green', 'Blue', 'Yellow', 'Cyan', 'Magenta', 'White'};
            app.LEDDropDown.ValueChangedFcn = createCallbackFcn(app, @LEDDropDownValueChanged, true);
            app.LEDDropDown.Value = 'OFF';
            app.UPDATEButton_2 = uibutton(app.DoFArmControlUIFigure, 'push');
            app.UPDATEButton_2.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButton_2Pushed, true);
            app.UPDATEButton_2.Text = 'UPDATE';
            app.SENDButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.VIEWButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.VIEWButton.ButtonPushedFcn = createCallbackFcn(app, @VIEWButtonPushed, true);
            app.VIEWButton.Text = 'VIEW';
            app.STOPButton_2 = uibutton(app.DoFArmControlUIFigure, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton_2.Text = 'STOP';
            % Show the figure after all components are created
            app.DoFArmControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSS_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoFArmControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoFArmControlUIFigure)
        end
    end
end